classdef Results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create panels with the corresponding data 
            % 640,480 => size
                
            g = uigridlayout(app.UIFigure, [5 1]);

            %             p = uipanel(g) % "BorderType", "none");
%             ax = uicontrol(p, "Style","text")
            
            % ONE SECTION
            songTitle = 'SONG TITLE';
            artist = 'ARTIST';
            coverImgSrc = 'https://i.scdn.co/image/ab67616d0000b2736c5ed8ec6664ac39910a821b'
            audioSrc = 'https://p.scdn.co/mp3-preview/1e7df67cef52cd4578f2a344f4bc875cac394301?cid=28ca2d3814414c08858c196d669a3838'
            
            pnl = uipanel(g);
            section = uigridlayout(pnl, [1 3]);
            section.ColumnWidth = {'fit', 'fit', 'fit', 'fit'}
            
            urlwrite(coverImgSrc, 'test1.png')
            cover = uiimage(section,'ImageSource','test1.png','ScaleMethod','stretch')

            songName = uilabel(section, 'Text',songTitle,'WordWrap','on','FontSize',24);
            artistName = uilabel(section, 'Text',artist,'WordWrap','on','FontSize',24);
            
            songClip = uihtml(section);
            urlwrite(audioSrc, 'testAudio.wav')
            
            songClip.HTMLSource = '<audio controls style="padding-left:20px"><source src="testAudio.wav" type="audio/wav"></audio>'
            
            % uihyperlink

            %p1 = uipanel(g); %uipanel(g, "BorderType","none");
          

%             p2 = uipanel(g);
%             p3 = uipanel(g);
%             p4 = uipanel(g);
%             p5 = uipanel(g);
            
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end